classdef Savelev < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        EditFieldLabel    matlab.ui.control.Label
        EditField         matlab.ui.control.EditField
        EditField_2Label  matlab.ui.control.Label
        EditField_2       matlab.ui.control.EditField
        Button            matlab.ui.control.Button
        Label             matlab.ui.control.Label
        Label_3           matlab.ui.control.Label
        Label_2           matlab.ui.control.Label
        Label_4           matlab.ui.control.Label
        Label_8           matlab.ui.control.Label
        Label_5           matlab.ui.control.Label
        Label_7           matlab.ui.control.Label
        Label_6           matlab.ui.control.Label
        Label_9           matlab.ui.control.Label
        ERRORLabel        matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
        app.Label.Visible = false;
        app.Label_4.Visible = false;
        app.Label_5.Visible = false;
        app.Label_6.Visible = false;
        app.Label_7.Visible = false;
        app.Label_8.Visible = false;
        app.Label_9.Visible = false;
        app.ERRORLabel.Visible = false;
        
        try
        
        syms x y z;
        
        f1 = str2sym(app.EditField.Value);
        f2 = str2sym(app.EditField_2.Value);
        
        
        fsurf([transpose(solve(f1)) solve(f2)]);


        a = solve([f1 f2],[x y],'Real',true);


        if size(strfind(app.EditField_2.Value,'x')) ~= 0 | size(strfind(app.EditField_2.Value,'y')) ~= 0
               
                app.Label.Visible = true;
                app.Label_4.Visible = true;
                app.Label_5.Visible = true;
                app.Label_6.Visible = true;
                app.Label_7.Visible = true;
                app.Label_8.Visible = true;
                app.Label.Text = string(a.x);
                app.Label_4.Text = string(a.y); 
             
             
        elseif size(strfind(app.EditField_2.Value,'z')) ~= 0
               if strfind(app.EditField.Value,'x') & strfind(app.EditField.Value,'z')
                f_new = replace(app.EditField.Value,'z',"("+string(solve(f2,z))+")");
                app.Label.Text = string(solve(str2sym(f_new),x,'Real',true));
                    if size(solve(str2sym(f_new),x,'Real',true)) ~= 0
                    app.Label.Visible = true;
                    app.Label_6.Visible = true;
                    app.Label_8.Visible = true;
                    else 
                   app.Label_9.Visible = true;
                    end
               elseif strfind(app.EditField.Value,'y') & strfind(app.EditField.Value,'z')
                f_new = replace(app.EditField.Value,'z',"("+string(solve(f2,z))+")");
                app.Label_4.Text = string(solve(str2sym(f_new),y,'Real',true));
                    if size(solve(str2sym(f_new),y,'Real',true)) ~= 0
                    app.Label_4.Visible = true;
                    app.Label_7.Visible = true;
                    app.Label_5.Visible = true;
                    else 
                   app.Label_9.Visible = true;
                    end
               elseif size(strfind(app.EditField.Value,'z')) == 0 & strfind(app.EditField.Value,'y')
                   app.Label.Text = string(solve(f1,y,"Real",true));
                   if size(solve(f1,y,'Real',true)) ~= 0
                   app.Label.Visible = true;
                   app.Label_6.Visible = true;
                   app.Label_8.Visible = true;
                   else 
                   app.Label_9.Visible = true;
                   end
               elseif size(strfind(app.EditField.Value,'z')) == 0 & strfind(app.EditField.Value,'x')
                   app.Label.Text = string(solve(f1,x,"Real",true));
                   if size(solve(f1,x,'Real',true)) ~= 0
                   app.Label_4.Visible = true;
                   app.Label_7.Visible = true;
                   app.Label_5.Visible = true;
                   else 
                   app.Label_9.Visible = true;
                   end
               else 
                   app.Label_9.Visible = true;
               end
        else
             app.Label_9.Visible = true;
        end
       
        catch
            app.ERRORLabel.Visible = true;
        end;


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = '=0';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField_2Label = uilabel(app.UIFigure);
            app.EditField_2Label.Text = '=0';
            app.EditField_2 = uieditfield(app.UIFigure, 'text');
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = 'Построить';
            app.Label = uilabel(app.UIFigure);
            app.Label.Visible = 'off';
            app.Label.Text = '';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = 'Поверхность:';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = 'Плоскость:';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '';
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Visible = 'off';
            app.Label_8.Text = 'x=';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Visible = 'off';
            app.Label_5.Text = '[';
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Visible = 'off';
            app.Label_7.Text = 'y=';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Visible = 'off';
            app.Label_6.Text = '[';
            app.Label_9 = uilabel(app.UIFigure);
            app.Label_9.Visible = 'off';
            app.Label_9.Text = {'Заданная плокость и поверхность не '; 'пересекаются в трехмерном пространстве.'};
            app.ERRORLabel = uilabel(app.UIFigure);
            app.ERRORLabel.Visible = 'off';
            app.ERRORLabel.Text = 'ERROR';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Savelev

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end